classdef Section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnternumberofbinsEditField  matlab.ui.control.NumericEditField
        EnternumberofbinsEditFieldLabel  matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        M0Label_3                   matlab.ui.control.Label
        M0Label_2                   matlab.ui.control.Label
        M0Label                     matlab.ui.control.Label
        T_maxEditField              matlab.ui.control.NumericEditField
        T_maxEditFieldLabel         matlab.ui.control.Label
        M_tButton                   matlab.ui.control.Button
        ThirdMomentLabel            matlab.ui.control.Label
        rdMomentLabel               matlab.ui.control.Label
        VarianceLabel               matlab.ui.control.Label
        VarianceValueLabel          matlab.ui.control.Label
        MeanValueLabel              matlab.ui.control.Label
        MeanLabel                   matlab.ui.control.Label
        StatisticsButton            matlab.ui.control.Button
        FilePathEditField           matlab.ui.control.EditField
        FilePathEditFieldLabel      matlab.ui.control.Label
        CDFButton                   matlab.ui.control.Button
        LoadFileButton              matlab.ui.control.Button
        PDFButton                   matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        UIAxesMoments               matlab.ui.control.UIAxes
        UIAxesCDF                   matlab.ui.control.UIAxes
        UIAxesPDF                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename; % Description
        space;
        PDF;
        bin_width;
        bin_cen;
        prob_of_X;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PDFButton
        function PDFButtonPushed(app, event)
  
binsNum=app.EnternumberofbinsEditField.Value;
    if isempty(binsNum) || binsNum <= 0
        uialert(app.UIFigure, 'Please enter a positive value for bins.', 'Invalid Input');
        return;
    end
Edge = linspace(min(app.space),max(app.space),binsNum+1);
% Calculate the histogram count and edges
[count, edges] = histcounts(app.space, Edge);

% Calculate the bin width (difference between edges)
app.bin_width = diff(Edge);
    

%CAlC THE PDF
    app.PDF= count/(sum(count)*app.bin_width(1));

    app.bin_cen = Edge(1:end-1) + app.bin_width / 2; % Bin centers for plotting

    X_interploation = linspace(min(app.bin_cen), max(app.bin_cen), 1000);  % 1000 points for smoothness
    Y_interploation = interp1(app.bin_cen, app.PDF, X_interploation, 'linear');

    % Plot the PDF as a histogram
    H = histogram(app.space, binsNum, 'Normalization', 'probability','Parent',app.UIAxesPDF);
    probability = H.Values;
    app.prob_of_X = probability;

    cla(app.UIAxesPDF);
    plot(app.UIAxesPDF, X_interploation, Y_interploation, 'LineWidth',3,'Color','magenta');
    xlabel(app.UIAxesPDF, 'Value');
    ylabel(app.UIAxesPDF, 'Probability Density');
    title(app.UIAxesPDF, 'PDF');
        end

        % Button pushed function: CDFButton
        function CDFButtonPushed(app, event)
           
    CDF=cumsum(app.PDF.*app.bin_width); 
    % Plot the CDF
    plot(app.UIAxesCDF, app.bin_cen, CDF,'-x','LineWidth',3,'Color','magenta');
    title(app.UIAxesCDF, 'Cumulative Distribution Function (CDF)');
    xlabel(app.UIAxesCDF, 'Values');
    ylabel(app.UIAxesCDF, 'Cumulative Probability');


        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Choose The File for the sample space");
    if isequal(file, 0)
        % User canceled the file selection
      
    else
        % Construct full file path
        fullpath = fullfile(path, file);
      
    end
    data = load(fullfile(path,file));
    field_names = fieldnames(data);
    app.space = data.(field_names{1});
    app.FilePathEditField.Value = file;

        end

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)
            
            app.filename = app.FilePathEditField.Value;
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            EX = sum(app.bin_cen.*app.prob_of_X);
            EX_2 = sum((app.bin_cen.^2) .* app.prob_of_X);
            X_variance = EX_2 - EX^2;
            EX_3 = sum(((app.bin_cen-EX).^3).*app.prob_of_X);
      

            app.MeanValueLabel.Text = sprintf('%.4f',EX);
            app.VarianceValueLabel.Text = sprintf('%.4f',X_variance);
            app.ThirdMomentLabel.Text = sprintf('%.4f',EX_3);
        end

        % Button pushed function: M_tButton
        function M_tButtonPushed(app, event)
            maxT = app.T_maxEditField.Value;

            %Check
    if isempty(maxT) || maxT <= 0
        uialert(app.UIFigure, 'Please enter a positive value for maxT.', 'Invalid Input');
        return;
    end
    rangeT = linspace(-1,maxT);
    
    MT = arrayfun(@(t) mean(exp(t .* app.space)), rangeT);
    MT_prime = arrayfun(@(t) mean(app.space .* exp(t * app.space)), rangeT);
    MT_prime2 = arrayfun(@(t) mean((app.space.^2) .* exp(t * app.space)), rangeT);

     % Calculate values at t = 0
    MT_0 = 1; % M(0) is always 1
    MT_prime_0 = mean(app.space); % Mean of the sample space
    MT_prime2_0 = mean(app.space.^2); % Second moment
     % Display the results
    % msg = sprintf(['At t = 0:\nM(0) = %.2f\nM''(0) = %.2f (Mean)\nM''''(0) = %.2f ' ...
    %                '(Second Moment)'], MT_0, MT_prime_0, MT_prime2_0);
    % uialert(app.UIFigure, msg, 'Results at t = 0');

    app.Label.Text = sprintf('%.4f',MT_0);
    app.Label_2.Text = sprintf('%.4f',MT_prime_0);
    app.Label_3.Text = sprintf('%.4f',MT_prime2_0);


    plot(app.UIAxesMoments, rangeT, MT, 'b--', 'LineWidth', 3, 'DisplayName', 'M(t)', 'Color', 'magenta');
    plot(app.UIAxes, rangeT, MT_prime, 'm:', 'LineWidth', 3, 'DisplayName', 'M''(t)', 'Color', 'magenta');
    plot(app.UIAxes2, rangeT, MT_prime2, 'c-', 'LineWidth', 3, 'DisplayName', 'M''''(t)', 'Color', 'magenta');

    % legend(app.UIAxesMoments, 'Location', 'best');
    % title(app.UIAxesMoments, 'MGF and Derivatives');
    % xlabel(app.UIAxesMoments, 't');
    % ylabel(app.UIAxesMoments, 'Value');
% 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesPDF = uiaxes(app.UIFigure);
            title(app.UIAxesPDF, 'PDF')
            colormap(app.UIAxesPDF, 'sky')
            app.UIAxesCDF = uiaxes(app.UIFigure);
            title(app.UIAxesCDF, 'CDF')
            colormap(app.UIAxesCDF, 'sky')
            app.UIAxesMoments = uiaxes(app.UIFigure);
            title(app.UIAxesMoments, 'M(t)')
            colormap(app.UIAxesMoments, 'sky')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'M''(t)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'M''''(t)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PDFButton = uibutton(app.UIFigure, 'push');
            app.PDFButton.ButtonPushedFcn = createCallbackFcn(app, @PDFButtonPushed, true);
            app.PDFButton.Text = 'PDF';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.LoadFileButton.Icon = fullfile(pathToMLAPP, 'LOAD.jpg');
            app.CDFButton = uibutton(app.UIFigure, 'push');
            app.CDFButton.ButtonPushedFcn = createCallbackFcn(app, @CDFButtonPushed, true);
            app.CDFButton.Text = 'CDF';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.StatisticsButton = uibutton(app.UIFigure, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Text = 'Statistics';
            app.MeanLabel = uilabel(app.UIFigure);
            app.MeanLabel.Text = 'Mean';
            app.MeanValueLabel = uilabel(app.UIFigure);
            app.MeanValueLabel.Text = '';
            app.VarianceValueLabel = uilabel(app.UIFigure);
            app.VarianceValueLabel.Text = '';
            app.VarianceLabel = uilabel(app.UIFigure);
            app.VarianceLabel.Text = 'Variance';
            app.rdMomentLabel = uilabel(app.UIFigure);
            app.rdMomentLabel.Text = '3rd Moment';
            app.ThirdMomentLabel = uilabel(app.UIFigure);
            app.ThirdMomentLabel.Text = '';
            app.M_tButton = uibutton(app.UIFigure, 'push');
            app.M_tButton.ButtonPushedFcn = createCallbackFcn(app, @M_tButtonPushed, true);
            app.M_tButton.Text = 'M_t';
            app.T_maxEditFieldLabel = uilabel(app.UIFigure);
            app.T_maxEditFieldLabel.Text = 'T_max';
            app.T_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.M0Label = uilabel(app.UIFigure);
            app.M0Label.Text = 'M(0)';
            app.M0Label_2 = uilabel(app.UIFigure);
            app.M0Label_2.Text = 'M''(0)';
            app.M0Label_3 = uilabel(app.UIFigure);
            app.M0Label_3.Text = 'M''''(0)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.EnternumberofbinsEditFieldLabel = uilabel(app.UIFigure);
            app.EnternumberofbinsEditFieldLabel.Text = 'Enter number of bins';
            app.EnternumberofbinsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Section1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end