classdef Section2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StatisticsButton            matlab.ui.control.Button
        CorrValue                   matlab.ui.control.Label
        CorrelationCoeffLabel       matlab.ui.control.Label
        CoValue                     matlab.ui.control.Label
        CovarianceLabel             matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        EnternumberofbinsEditField  matlab.ui.control.NumericEditField
        EnternumberofbinsEditFieldLabel  matlab.ui.control.Label
        FilePathEditField           matlab.ui.control.EditField
        FilePathEditFieldLabel      matlab.ui.control.Label
        LoadFileButton              matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename; % Description
        space;
        centersofX;
        centersofY;
        Xmarginal;
        Ymarginal;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
    [file, path] = uigetfile("*.mat", "Choose The File for the sample space");
    if isequal(file, 0)
        % User canceled the file selection
      
    else
        % Construct full file path
        fullpath = fullfile(path, file);
      
    end
    data = load(fullfile(path,file));
    field_names = fieldnames(data);
    app.space = data.(field_names{1});
    app.FilePathEditField.Value = file;
        end

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)

            app.filename = app.FilePathEditField.Value;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x = app.space(1,:);
            y = app.space(2,:);
            % n=length(X);
%             covariance = sum((X - mean_X) .* (Y - mean_Y)) / n;
% std_X = sqrt(sum((X - mean_X).^2) / n); % Standard deviation of X
% std_Y = sqrt(sum((Y - mean_Y).^2) / n); % Standard deviation of Y
% correlation_coefficient = covariance / (std_X * std_Y);

 % Prompt the user for the number of bins
    binsNum = app.EnternumberofbinsEditField.Value;
     if isempty(binsNum) || binsNum <= 0
        uialert(app.UIFigure, 'Please enter a positive value for bins.', 'Invalid Input');
        return;
    end

    [count, edgesofX, edgesofY] = histcounts2(x, y, binsNum, 'Normalization', 'probability');

    % Convert edges to bin centers for 3D plotting
    x_cen = edgesofX(1:end-1) + diff(edgesofX) / 2;
    y_cen = edgesofY(1:end-1) + diff(edgesofY) / 2;

    % Create a meshgrid for the centers
    [x_grid, y_grid] = meshgrid(x_cen, y_cen);


    % Plot the joint probability distribution as a 3D surface
    surf(app.UIAxes, x_grid, y_grid, count');
    title(app.UIAxes, 'Joint Probability Distribution of X and Y');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    zlabel(app.UIAxes, 'Probability');
    colormap(app.UIAxes, 'jet');
    shading(app.UIAxes, 'interp');


      % Marginal distribution of X
    HX = histogram(x, binsNum, 'Normalization', 'probability', 'Parent', app.UIAxes2);
    marginalofX = HX.Values;
    app.Xmarginal = marginalofX;
    app.centersofX = x_cen;
    x_interpolation_X = linspace(min(x_cen), max(x_cen), 1000);
    y_interpolation_X = interp1(x_cen, marginalofX, x_interpolation_X, 'linear');
    plot(app.UIAxes2, x_interpolation_X, y_interpolation_X, 'LineWidth', 3, 'Color', 'magenta');
    title(app.UIAxes2, 'Marginal Distribution of X');
    xlabel(app.UIAxes2, 'X');
    ylabel(app.UIAxes2, 'Probability');

% % Marginal distribution of Y
    HY = histogram(y, binsNum, 'Normalization', 'probability', 'Parent', app.UIAxes3);
    marginalofX = HY.Values;
    app.Ymarginal = marginalofX;
    app.centersofY = y_cen;
    x_interpolation_Y = linspace(min(y_cen), max(y_cen), 1000);
    y_interpolation_Y = interp1(y_cen, marginalofX, x_interpolation_Y, 'linear');
    plot(app.UIAxes3, x_interpolation_Y, y_interpolation_Y, 'LineWidth', 3, 'Color', 'magenta');
    title(app.UIAxes3, 'Marginal Distribution of Y');
    xlabel(app.UIAxes3, 'Y');
    ylabel(app.UIAxes3, 'Probability');

        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            x = app.space(1,:);
            y = app.space(2,:);
            num = length(x);
            EX = sum(app.centersofX.*app.Xmarginal);
            EY = sum(app.centersofY.*app.Ymarginal);
            cov = sum((x - EX) .* (y - EY)) / num;
            std_x = sqrt(sum((x - EX).^2) / num); % Standard deviation of X
            std_y = sqrt(sum((y - EY).^2) / num); % Standard deviation of Y
            CorrCoeff = cov / (std_x * std_y);


            app.CoValue.Text=sprintf('%.4f',cov);
            app.CorrValue.Text=sprintf('%.4f',CorrCoeff);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Joint Probability Distribution (X) (Y)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Probability')
            colormap(app.UIAxes, 'sky')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Marginal Distribution (X)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Probability')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'sky')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Marginal Distribution (Y)')
            xlabel(app.UIAxes3, 'Y')
            ylabel(app.UIAxes3, 'Probability')
            zlabel(app.UIAxes3, 'Z')
            colormap(app.UIAxes3, 'sky')
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.EnternumberofbinsEditFieldLabel = uilabel(app.UIFigure);
            app.EnternumberofbinsEditFieldLabel.Text = 'Enter number of bins';
            app.EnternumberofbinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.CovarianceLabel = uilabel(app.UIFigure);
            app.CovarianceLabel.Text = 'Covariance';
            app.CoValue = uilabel(app.UIFigure);
            app.CoValue.Text = '';
            app.CorrelationCoeffLabel = uilabel(app.UIFigure);
            app.CorrelationCoeffLabel.Text = 'Correlation Coeff.';
            app.CorrValue = uilabel(app.UIFigure);
            app.CorrValue.Text = '';
            app.StatisticsButton = uibutton(app.UIFigure, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Text = 'Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Section2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end