classdef Section3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Wex23YEditField                 matlab.ui.control.EditField
        Wex23YEditFieldLabel            matlab.ui.control.Label
        Zex2X1EditField                 matlab.ui.control.EditField
        ZegLabel                        matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        FilePathEditField               matlab.ui.control.EditField
        FilePathEditFieldLabel          matlab.ui.control.Label
        LoadFileButton                  matlab.ui.control.Button
        EnternumbrofbinsEditField       matlab.ui.control.NumericEditField
        EnternumbrofbinsEditFieldLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filename; % Description
        space; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FilePathEditField
        function FilePathEditFieldValueChanged(app, event)

            app.filename = app.FilePathEditField.Value;
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
    [file, path] = uigetfile("*.mat", "Choose The File for the sample space");
    if isequal(file, 0)
        % User canceled the file selection
      
    else
        % Construct full file path
        fullpath = fullfile(path, file);
      
    end
    data = load(fullfile(path,file));
    field_names = fieldnames(data);
    app.space = data.(field_names{1});
    app.FilePathEditField.Value = file;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
    % Extract X and Y
    x = app.space(1, :);
    y = app.space(2, :);

    % Prompt the user for Z and W expressions
    Z = app.Zex2X1EditField.Value;
    W = app.Wex23YEditField.Value;

    numofbins = app.EnternumbrofbinsEditField.Value;
     if isempty(numofbins) || numofbins <= 0
        uialert(app.UIFigure, 'Please enter a positive value for bins.', 'Invalid Input');
        return;
    end

    % Compute Z and W
    try
        z = eval(Z); % Evaluate the expression for Z
        w = eval(W); % Evaluate the expression for W
    catch ME
        error('Error evaluating Z or W: %s', ME.message);
    end


    [count, edgesofZ, edgesW] = histcounts2(z, w, numofbins, 'Normalization', 'probability');

    % Convert edges to bin centers for 3D plotting
    z_cen = edgesofZ(1:end-1) + diff(edgesofZ) / 2;
    w_cen = edgesW(1:end-1) + diff(edgesW) / 2;

    % Create a meshgrid for the centers
    [z_grid, w_grid] = meshgrid(z_cen, w_cen);
 % Plot the joint probability distribution as a 3D surface
    surf(app.UIAxes,z_grid, w_grid, count');
    title(app.UIAxes, 'Joint Probability Distribution of X and Y');
    xlabel(app.UIAxes, 'Z');
    ylabel(app.UIAxes, 'W');
    zlabel(app.UIAxes, 'Probability');
    colormap(app.UIAxes, 'jet');
    shading(app.UIAxes, 'interp');


      % Marginal distribution of Z
    HZ = histogram(z, numofbins, 'Normalization', 'probability', 'Parent', app.UIAxes2);
    marginalofZ = HZ.Values;
    z_interpolation_X = linspace(min(z_cen), max(z_cen), 1000);
    z_interpolation_Y = interp1(z_cen, marginalofZ, z_interpolation_X, 'linear');
    plot(app.UIAxes2, z_interpolation_X, z_interpolation_Y, 'LineWidth', 3, 'Color', 'magenta');
    title(app.UIAxes2,'Marginal Distribution of Z');
    xlabel(app.UIAxes2, 'Z');
    ylabel(app.UIAxes2, 'Probability');

% % Marginal distribution of W
    HW = histogram(w, numofbins, 'Normalization', 'probability', 'Parent', app.UIAxes3);
    marginalofW = HW.Values;
    w_interpolation_X = linspace(min(w_cen), max(w_cen), 1000);
    w_interpolation_Y = interp1(w_cen, marginalofW, w_interpolation_X, 'linear');
    plot(app.UIAxes3, w_interpolation_X, w_interpolation_Y, 'LineWidth', 3, 'Color', 'magenta');
    title(app.UIAxes3, 'Marginal Distribution of W');
    xlabel(app.UIAxes3, 'W');
    ylabel(app.UIAxes3, 'Probability');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Joint Probability Distribution of Z and W')
            xlabel(app.UIAxes, 'Z')
            ylabel(app.UIAxes, 'W')
            zlabel(app.UIAxes, 'Probability')
            colormap(app.UIAxes, 'sky')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Marginal of Z')
            xlabel(app.UIAxes2, 'Z')
            ylabel(app.UIAxes2, 'Probability')
            zlabel(app.UIAxes2, 'Z')
            colormap(app.UIAxes2, 'sky')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Marginal of W')
            xlabel(app.UIAxes3, 'W')
            ylabel(app.UIAxes3, 'Probability')
            zlabel(app.UIAxes3, 'Z')
            colormap(app.UIAxes3, 'sky')
            app.EnternumbrofbinsEditFieldLabel = uilabel(app.UIFigure);
            app.EnternumbrofbinsEditFieldLabel.Text = 'Enter numbr of bins';
            app.EnternumbrofbinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FilePathEditFieldLabel = uilabel(app.UIFigure);
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.FilePathEditField.ValueChangedFcn = createCallbackFcn(app, @FilePathEditFieldValueChanged, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ZegLabel = uilabel(app.UIFigure);
            app.ZegLabel.Text = 'Z  ( e.x"2*X-1") ';
            app.Zex2X1EditField = uieditfield(app.UIFigure, 'text');
            app.Wex23YEditFieldLabel = uilabel(app.UIFigure);
            app.Wex23YEditFieldLabel.Text = 'W (e.x"2-3*Y")';
            app.Wex23YEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Section3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end